classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        TabGroup                   matlab.ui.container.TabGroup
        Reprsentation3DTab         matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        GridLayout7                matlab.ui.container.GridLayout
        GrappinCheckBox            matlab.ui.control.CheckBox
        NacelleCheckBox            matlab.ui.control.CheckBox
        GrappinLabel               matlab.ui.control.Label
        NacelleLabel               matlab.ui.control.Label
        GridLayout6                matlab.ui.container.GridLayout
        ArGVSlider                 matlab.ui.control.Slider
        ArGVSliderLabel            matlab.ui.control.Label
        ArDVSlider                 matlab.ui.control.Slider
        ArDVSliderLabel            matlab.ui.control.Label
        AvGVSlider                 matlab.ui.control.Slider
        AvGVSliderLabel            matlab.ui.control.Label
        AvDVSlider                 matlab.ui.control.Slider
        AvDVSliderLabel            matlab.ui.control.Label
        GridLayout4                matlab.ui.container.GridLayout
        Angle4                     matlab.ui.control.NumericEditField
        Angle3                     matlab.ui.control.NumericEditField
        Angle2                     matlab.ui.control.NumericEditField
        Angle1                     matlab.ui.control.NumericEditField
        AngleLabel                 matlab.ui.control.Label
        MoteurLabel                matlab.ui.control.Label
        ArriregaucheCheckBox       matlab.ui.control.CheckBox
        ArriredroitCheckBox        matlab.ui.control.CheckBox
        AvantgaucheCheckBox        matlab.ui.control.CheckBox
        AvantdroitCheckBox         matlab.ui.control.CheckBox
        GestiondescomposantsLabel  matlab.ui.control.Label
        Plot3D                     matlab.ui.control.UIAxes
        PerformanceTab             matlab.ui.container.Tab
        GridLayout5                matlab.ui.container.GridLayout
        ArG_plot                   matlab.ui.control.UIAxes
        AvG_plot                   matlab.ui.control.UIAxes
        ArD_plot                   matlab.ui.control.UIAxes
        AvD_plot                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % constant
        grav = 9.80665

        % graphique
        dirigeable_plot
        point_CG_changed;
        point_CG_changed_text;
        faces
        patch_av_d_motor; patch_av_d_tube; patch_av_d_box; patch_grav_center_av_d
        patch_av_g_motor; patch_av_g_tube; patch_av_g_box; patch_grav_center_av_g
        patch_ar_d_motor; patch_ar_d_tube; patch_ar_d_box; patch_grav_center_ar_d
        patch_ar_g_motor; patch_ar_g_tube; patch_ar_g_box; patch_grav_center_ar_g

        norm_weight_AvD;
        norm_weight_AvG;
        norm_weight_ArD;
        norm_weight_ArG;
        norm_weight_nacelle;
        norm_weight_grappin;

        patch_nacelle
        patch_grappin
        
        % rotation
        angle_1;
        angle_2;
        angle_3;
        angle_4;
        angle_vector;
        angle_1_changed = false;
        angle_2_changed = false;
        angle_3_changed = false;
        angle_4_changed = false;

        % component mass
        mass_AvD
        mass_AvG
        mass_ArD
        mass_ArG
        mass_nacelle
        mass_grappin
        mass_dirigeable
        mass_components
        CG_components
        CG_total

        % wieght motor
        weight_AvD
        weight_AvG
        weight_ArD
        weight_ArG
        weight_nacelle
        weight_grappin

        % motor_position
        position

        % position nacelle
        nacelle_position

        % position grappin
        grappling_position

        % statut
        state_av_d
        state_av_g
        state_ar_d
        state_ar_g
        state_nacelle
        state_grappin

        % centre de gravité
        grav_center
    end
    
    methods (Access = private)
        
        function [Vertices_motor, Vertices_tube, Vertices_block, PivotPoint, G_sm] = DefVeticesMotor(app, num)
            y_t = 0.2;
            z_t = 0.03;
            y_b = 0.4;

            if num == 1
                signe_y = 1;
                x_t1 = -0.08;
                x_t2 = 0.08;
                x_b1 = 0.1;
                x_b2 = -0.1;
            elseif num == 2
                signe_y = -1;
                x_t1 = -0.08;
                x_t2 = 0.08;
                x_b1 = 0.1;
                x_b2 = -0.1;
            elseif num == 3
                signe_y = 1;
                x_t1 = 0.08;
                x_t2 = -0.08;
                x_b1 = -0.1;
                x_b2 = 0.1;
            else
                signe_y = -1;
                x_t1 = 0.08;
                x_t2 = -0.08;
                x_b1 = -0.1;
                x_b2 = 0.1;
            end

            PivotPoint = [(app.position.x1(num) + app.position.x2(num))/2, 0, 0];

            Vertices_motor = [
                app.position.x1(num), app.position.y1(num), app.position.z1(num);
                app.position.x2(num), app.position.y1(num), app.position.z1(num);
                app.position.x2(num), app.position.y1(num), app.position.z2(num);
                app.position.x1(num), app.position.y1(num), app.position.z2(num);
                app.position.x1(num), app.position.y2(num), app.position.z1(num);
                app.position.x2(num), app.position.y2(num), app.position.z1(num);
                app.position.x2(num), app.position.y2(num), app.position.z2(num);
                app.position.x1(num), app.position.y2(num), app.position.z2(num)
                ];

            Vertices_tube = [
                app.position.x1(num) + x_t1, app.position.y1(num) - signe_y * y_t, app.position.z1(num) + z_t;
                app.position.x2(num) + x_t2, app.position.y1(num) - signe_y * y_t, app.position.z1(num) + z_t;
                app.position.x2(num) + x_t2, app.position.y1(num) - signe_y * y_t, app.position.z2(num) - z_t;
                app.position.x1(num) + x_t1, app.position.y1(num) - signe_y * y_t, app.position.z2(num) - z_t;
                app.position.x1(num) + x_t1, app.position.y2(num) - signe_y * y_t, app.position.z1(num) + z_t;
                app.position.x2(num) + x_t2, app.position.y2(num) - signe_y * y_t, app.position.z1(num) + z_t;
                app.position.x2(num) + x_t2, app.position.y2(num) - signe_y * y_t, app.position.z2(num) - z_t;
                app.position.x1(num) + x_t1, app.position.y2(num) - signe_y * y_t, app.position.z2(num) - z_t
                ];

            Vertices_block = [
                app.position.x1(num) + x_b1, app.position.y1(num) - signe_y * y_b, 2*app.position.z1(num);
                app.position.x2(num) + x_b2, app.position.y1(num) - signe_y * y_b, 2*app.position.z1(num);
                app.position.x2(num) + x_b2, app.position.y1(num) - signe_y * y_b, 2*app.position.z2(num);
                app.position.x1(num) + x_b1, app.position.y1(num) - signe_y * y_b, 2*app.position.z2(num);
                app.position.x1(num) + x_b1, app.position.y2(num) - signe_y * y_b, 2*app.position.z1(num);
                app.position.x2(num) + x_b2, app.position.y2(num) - signe_y * y_b, 2*app.position.z1(num);
                app.position.x2(num) + x_b2, app.position.y2(num) - signe_y * y_b, 2*app.position.z2(num);
                app.position.x1(num) + x_b1, app.position.y2(num) - signe_y * y_b, 2*app.position.z2(num)
                ];
            
            x_sm = ((app.position.x1(num) + app.position.x2(num))/2 + (app.position.x1(num) + x_t1 + app.position.x2(num) + x_t2)/2 + (app.position.x1(num) + x_b1 + app.position.x2(num) + x_b2)/2) / 3;
            y_sm = ((app.position.y1(num) + app.position.y2(num))/2 + (app.position.y1(num) - signe_y * y_t + app.position.y2(num) - signe_y * y_t)/2 + (app.position.y1(num) - signe_y * y_b + app.position.y2(num) - signe_y * y_b)/2) / 3;
            z_sm = ((app.position.z1(num) + app.position.z2(num))/2 + (app.position.z1(num) + z_t + app.position.z2(num) - z_t)/2 + (2*app.position.z1(num) + 2*app.position.z2(num))/2) / 3;
            
            G_sm = [x_sm, y_sm, z_sm];
        end
        
        function rot = motor_rotation(app, num)
            s = sin(app.angle_vector(num));
            c = cos(app.angle_vector(num));

            rot = [
                [c 0 s];
                [0 1 0];
                [-s 0 c]
                ];
        end

        function updateTotalCG(app)
            masses = [app.mass_dirigeable, app.mass_components];
            CGs = [app.grav_center; app.CG_components];
            
            total_mass = sum(masses);
            weighted_CG_sum = sum(CGs .* masses', 1);
            app.CG_total = weighted_CG_sum / total_mass;
            
            % Mettre à jour l'affichage du centre de gravité total si nécessaire
            disp(['Centre de gravité total: ', num2str(app.CG_total)]);
            
            delete(app.point_CG_changed)
            delete(app.point_CG_changed_text)
            app.point_CG_changed = plot3(app.Plot3D, app.CG_total(1), app.CG_total(2), app.CG_total(3), 'o', 'MarkerSize', 4, 'MarkerEdgeColor', 'b', 'MarkerFaceColor', [0, 0, 1]);
            app.point_CG_changed_text = text(app.Plot3D, app.CG_total(1), app.CG_total(2), app.CG_total(3) + 0.05, "G'", 'FontSize', 12, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            % Définition de dirigeable sur le graphique Plot3D
            app.mass_dirigeable = 3;
            a = 3;
            b = 0.75; 
            c = 0.75;
            [theta, phi] = meshgrid(linspace(0, 2*pi, 50), linspace(0, pi, 50));
            
            x = a * sin(phi) .* cos(theta);
            y = b * sin(phi) .* sin(theta);
            z = c * cos(phi);

            hold(app.Plot3D, 'on');
            app.dirigeable_plot =  surf(app.Plot3D, x, y, z, ...
                                        'FaceColor','blue', ...
                                        'FaceAlpha',0.25, ...
                                        'EdgeColor','none');

            view(app.Plot3D, 3);
            grid(app.Plot3D, 'on');
            xlim(app.Plot3D, [-3, 3]);
            ylim(app.Plot3D, [-2, 2]);
            zlim(app.Plot3D, [-2, 2]);

            % Initialisation du centre de gravité
            app.grav_center = [3 - 6 * 0.41, 0, 0];
            plot3(app.Plot3D, app.grav_center(1), 0, 0, 'o', 'MarkerSize', 4, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', [0.5, 0, 0.5]);
            text(app.Plot3D, app.grav_center(1), 0, 0.05, 'G', 'FontSize', 12, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom');
            quiver3(app.Plot3D, 0, 0, 0, 1, 0, 0, 'Color','k', 'LineWidth', 1)
            quiver3(app.Plot3D, 0, 0, 0, 0, 1, 0, 'Color','k', 'LineWidth', 1)
            quiver3(app.Plot3D, 0, 0, 0, 0, 0, 1, 'Color','k', 'LineWidth', 1)
            hold(app.Plot3D, 'off');

            % Définition de la face
            app.faces = [
                1 2 3 4;
                5 6 7 8;
                1 2 6 5;
                2 3 7 6;
                3 4 8 7;
                4 1 5 8
                ];


            % Chargement des positions des moteurs
            current_folder = pwd;
            data_folder = fullfile(current_folder, 'data/');
            try
                app.position = load(fullfile(data_folder, "MotorPosition.mat"));
                disp("Chargement des positions des moteurs validé.")
            catch e
                disp(e)
            end
            try
                app.nacelle_position = load(fullfile(data_folder, "NacellePosition.mat"));
                disp("Chargement de la position de la nacelle validé.")
            catch e
                disp(e)
            end
            try
                app.grappling_position = load(fullfile(data_folder, "GrapplingHook.mat"));
                disp("Chargement de la position du grappin.")
                disp(app.grappling_position)
            catch e
                disp(e)
            end
            % valeur init des angles moteur
            app.angle_1 = app.Angle1.Value;
            app.angle_2 = app.Angle2.Value;
            app.angle_3 = app.Angle3.Value;
            app.angle_4 = app.Angle4.Value;
            app.angle_vector = zeros(1, 4);

            % Initialiser le graphique
            xlabel(app.AvD_plot, 'Voltage (V)');
            ylabel(app.AvD_plot, 'Thrust (N)');
            title(app.AvD_plot, 'Motor Performance');

            % initialisation centre de gravité
            app.mass_components = zeros(1, 6);
            app.CG_components = zeros(6, 3);
        end

        % Value changed function: AvantdroitCheckBox
        function AvantdroitCheckBoxValueChanged(app, event)
            app.state_av_d = app.AvantdroitCheckBox.Value;

            delete(app.patch_av_d_motor);
            delete(app.patch_av_d_tube);
            delete(app.patch_av_d_box)
            delete(app.patch_grav_center_av_d);
            delete(app.norm_weight_AvD);
            app.patch_av_d_motor = [];
            app.patch_av_d_tube = [];
            app.patch_av_d_box = [];
            app.patch_grav_center_av_d = [];

            if app.state_av_d == 1 || app.angle_1_changed == true
                disp("génération moteur avant droit")
                [Vertices_av_d_motor, Vertices_av_d_tube, Vertices_av_d_box, PivotPoint, G_sm_AvD] = app.DefVeticesMotor(1);
                
                % rotation du moteur AvD
                Vertices_av_d_motor = (app.motor_rotation(1).' * (Vertices_av_d_motor - PivotPoint(1, :)).' + PivotPoint(1, :).').';
                
                hold(app.Plot3D, 'on')
                app.patch_grav_center_av_d = plot3(app.Plot3D, G_sm_AvD(1), G_sm_AvD(2), G_sm_AvD(3), 'o', 'MarkerSize', 5, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'k');
                app.patch_av_d_motor = patch(app.Plot3D, 'Vertices', Vertices_av_d_motor, 'Faces', app.faces, 'FaceColor', 'r', 'FaceAlpha', 0.5);
                app.patch_av_d_tube = patch(app.Plot3D, 'Vertices', Vertices_av_d_tube, 'Faces', app.faces, 'FaceColor', 'b', 'FaceAlpha', 0.5);
                app.patch_av_d_box = patch(app.Plot3D, 'Vertices', Vertices_av_d_box, 'Faces', app.faces, 'FaceColor', 'g', 'FaceAlpha', 0.5);

                % assignation de la masse du moteur AvD
                app.mass_AvD = 0.145;
                app.weight_AvD = app.mass_AvD * app.grav;
                app.norm_weight_AvD = quiver3(app.Plot3D, G_sm_AvD(1), G_sm_AvD(2), G_sm_AvD(3), 0, 0, -0.5, 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);

                % modification du centre de gravité
                app.mass_components(1) = app.mass_AvD;
                app.CG_components(1, :) = G_sm_AvD;
                app.updateTotalCG();
            else

                % modification du centre de gravité
                app.mass_components(1) = 0;
                app.CG_components(1, :) = [0, 0, 0];
                app.updateTotalCG();

                if ~isempty(app.patch_av_d_motor) && isvalid(app.patch_av_d_motor)
                    disp("suppression moteur avant droit")
                    delete(app.patch_grav_center_av_d);
                    delete(app.patch_av_d_motor);
                    delete(app.patch_av_d_tube);
                    delete(app.patch_av_d_box);
                    delete(app.norm_weight_AvD);
                    app.patch_grav_center_av_d = [];
                    app.patch_av_d_motor = [];
                    app.patch_av_d_tube = [];
                    app.patch_av_d_box = [];
                end
            end
        end

        % Value changed function: AvantgaucheCheckBox
        function AvantgaucheCheckBoxValueChanged(app, event)
            app.state_av_g = app.AvantgaucheCheckBox.Value;

            delete(app.patch_av_g_motor);
            delete(app.patch_av_g_tube);
            delete(app.patch_av_g_box);
            delete(app.patch_grav_center_av_g);
            delete(app.norm_weight_AvG);
            app.patch_av_g_motor = [];
            app.patch_av_g_tube = [];
            app.patch_av_g_box = [];
            app.patch_grav_center_av_g = [];

            if app.state_av_g == 1 || app.angle_2_changed == true
                disp("génération moteur avant gauche")
                [Vertices_av_g_motor, Vertices_av_g_tube, Vertices_av_g_box, PivotPoint, G_sm_AvG] = app.DefVeticesMotor(2);

                % rotation du moteur AvG
                Vertices_av_g_motor = (app.motor_rotation(2).' * (Vertices_av_g_motor - PivotPoint(1, :)).' + PivotPoint(1, :).').';

                hold(app.Plot3D, 'on')
                app.patch_grav_center_av_g = plot3(app.Plot3D, G_sm_AvG(1), G_sm_AvG(2), G_sm_AvG(3), 'o', 'MarkerSize', 5, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'k');
                app.patch_av_g_motor = patch(app.Plot3D, 'Vertices', Vertices_av_g_motor, 'Faces', app.faces, 'FaceColor', 'r', 'FaceAlpha', 0.5);
                app.patch_av_g_tube = patch(app.Plot3D, 'Vertices', Vertices_av_g_tube, 'Faces', app.faces, 'FaceColor', 'b', 'FaceAlpha', 0.5);
                app.patch_av_g_box = patch(app.Plot3D, 'Vertices', Vertices_av_g_box, 'Faces', app.faces, 'FaceColor', 'g', 'FaceAlpha', 0.5);

                % assignation de la masse du moteur AvG
                app.mass_AvG = 0.145;
                app.weight_AvG = app.mass_AvG * app.grav;
                app.norm_weight_AvG = quiver3(app.Plot3D, G_sm_AvG(1), G_sm_AvG(2), G_sm_AvG(3), 0, 0, -0.5, 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);

                % modification du centre de gravité
                app.mass_components(2) = app.mass_AvG;
                app.CG_components(2, :) = G_sm_AvG;
                app.updateTotalCG();
            else

                % modification du centre de gravité
                app.mass_components(2) = 0;
                app.CG_components(2, :) = [0, 0, 0];
                app.updateTotalCG();

                if ~isempty(app.patch_av_g_motor) && isvalid(app.patch_av_g_motor)
                    disp("suppression moteur avant gauche")
                    delete(app.patch_grav_center_av_g);
                    delete(app.patch_av_g_motor);
                    delete(app.patch_av_g_tube);
                    delete(app.patch_av_g_box);
                    delete(app.norm_weight_AvG);
                    app.patch_av_g_motor = [];
                    app.patch_av_g_tube = [];
                    app.patch_av_g_box = [];
                    app.patch_grav_center_av_g = [];
                end
            end     
        end

        % Value changed function: ArriredroitCheckBox
        function ArriredroitCheckBoxValueChanged(app, event)
            app.state_ar_d = app.ArriredroitCheckBox.Value;

            delete(app.patch_ar_d_motor);
            delete(app.patch_ar_d_tube);
            delete(app.patch_ar_d_box);
            delete(app.patch_grav_center_ar_d);
            delete(app.norm_weight_ArD);
            app.patch_ar_d_motor = [];
            app.patch_ar_d_tube = [];
            app.patch_ar_d_box = [];
            app.patch_grav_center_ar_d = [];

            if app.state_ar_d == 1 || app.angle_3_changed == true
                disp("génération moteur arrière droit")
                [Vertices_ar_d_motor, Vertices_ar_d_tube, Vertices_ar_d_box, PivotPoint, G_sm_ArD] = app.DefVeticesMotor(3);

                % rotation du moteur ArD
                Vertices_ar_d_motor = (app.motor_rotation(3).' * (Vertices_ar_d_motor - PivotPoint(1, :)).' + PivotPoint(1, :).').';

                hold(app.Plot3D, 'on')
                app.patch_grav_center_ar_d = plot3(app.Plot3D, G_sm_ArD(1), G_sm_ArD(2), G_sm_ArD(3), 'o', 'MarkerSize', 5, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'k');
                app.patch_ar_d_motor = patch(app.Plot3D, 'Vertices', Vertices_ar_d_motor, 'Faces', app.faces, 'FaceColor', 'r', 'FaceAlpha', 0.5);
                app.patch_ar_d_tube = patch(app.Plot3D, 'Vertices', Vertices_ar_d_tube, 'Faces', app.faces, 'FaceColor', 'b', 'FaceAlpha', 0.5);
                app.patch_ar_d_box = patch(app.Plot3D, 'Vertices', Vertices_ar_d_box, 'Faces', app.faces, 'FaceColor', 'g', 'FaceAlpha', 0.5);
            
                % assignation de la masse du moteur ArD
                app.mass_ArD = 0.145;
                app.weight_ArD = app.mass_ArD * app.grav;
                app.norm_weight_ArD = quiver3(app.Plot3D, G_sm_ArD(1), G_sm_ArD(2), G_sm_ArD(3), 0, 0, -0.5, 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);

                % modification du centre de gravité
                app.mass_components(3) = app.mass_ArD;
                app.CG_components(3, :) = G_sm_ArD;
                app.updateTotalCG();
            else

                % modification du centre de gravité
                app.mass_components(3) = 0;
                app.CG_components(3, :) = [0, 0, 0];
                app.updateTotalCG();

                if ~isempty(app.patch_ar_d_motor) && isvalid(app.patch_ar_d_motor)
                    disp("suppression moteur arrière droit")
                    delete(app.patch_grav_center_ar_d);
                    delete(app.patch_ar_d_motor);
                    delete(app.patch_ar_d_tube);
                    delete(app.patch_ar_d_box);
                    delete(app.norm_weight_ArD);
                    app.patch_ar_d_motor = [];
                    app.patch_ar_d_tube = [];
                    app.patch_ar_d_box = [];
                    app.patch_grav_center_ar_d = [];
                end
            end
        end

        % Value changed function: ArriregaucheCheckBox
        function ArriregaucheCheckBoxValueChanged(app, event)
            app.state_ar_g = app.ArriregaucheCheckBox.Value;

            delete(app.patch_ar_g_motor);
            delete(app.patch_ar_g_tube);
            delete(app.patch_ar_g_box);
            delete(app.patch_grav_center_ar_g);
            delete(app.norm_weight_ArG);
            app.patch_ar_g_motor = [];
            app.patch_ar_g_tube = [];
            app.patch_ar_g_box = [];
            app.patch_grav_center_ar_g = [];

            if app.state_ar_g == 1 || app.angle_4_changed == true
                disp("génération moteur arrière droit")
                [Vertices_ar_g_motor, Vertices_ar_g_tube, Vertices_ar_g_box, PivotPoint, G_sm_ArG] = app.DefVeticesMotor(4);

                % rotation du moteur ArG
                Vertices_ar_g_motor = (app.motor_rotation(4).' * (Vertices_ar_g_motor - PivotPoint(1, :)).' + PivotPoint(1, :).').';

                hold(app.Plot3D, 'on')
                app.patch_grav_center_ar_g = plot3(app.Plot3D, G_sm_ArG(1), G_sm_ArG(2), G_sm_ArG(3), 'o', 'MarkerSize', 5, 'MarkerEdgeColor', 'k', 'MarkerFaceColor', 'k');
                app.patch_ar_g_motor = patch(app.Plot3D, 'Vertices', Vertices_ar_g_motor, 'Faces', app.faces, 'FaceColor', 'r', 'FaceAlpha', 0.5);
                app.patch_ar_g_tube = patch(app.Plot3D, 'Vertices', Vertices_ar_g_tube, 'Faces', app.faces, 'FaceColor', 'b', 'FaceAlpha', 0.5);
                app.patch_ar_g_box = patch(app.Plot3D, 'Vertices', Vertices_ar_g_box, 'Faces', app.faces, 'FaceColor', 'g', 'FaceAlpha', 0.5);
            
                % assignation de la masse du moteur ArG
                app.mass_ArG = 0.145;
                app.weight_ArG = app.mass_ArG * app.grav;
                app.norm_weight_ArG = quiver3(app.Plot3D, G_sm_ArG(1), G_sm_ArG(2), G_sm_ArG(3), 0, 0, -0.5, 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);

                % modification du centre de gravité
                app.mass_components(4) = app.mass_ArG;
                app.CG_components(4, :) = G_sm_ArG;
                app.updateTotalCG();
            else

                % modification du centre de gravité
                app.mass_components(4) = 0;
                app.CG_components(4, :) = [0, 0, 0];
                app.updateTotalCG();    

                if ~isempty(app.patch_ar_g_motor) && isvalid(app.patch_ar_g_motor)
                    disp("suppression moteur arrière droit")
                    delete(app.patch_grav_center_ar_g);
                    delete(app.patch_ar_g_motor);
                    delete(app.patch_ar_g_tube);
                    delete(app.patch_ar_g_box);
                    delete(app.norm_weight_ArG);
                    app.patch_ar_g_motor = [];
                    app.patch_ar_g_tube = [];
                    app.patch_ar_g_box = [];
                    app.patch_grav_center_ar_g = [];
                end
            end
        end

        % Value changed function: Angle1
        function Angle1ValueChanged(app, event)
            app.angle_1 = app.Angle1.Value;
            app.angle_vector(1) = app.angle_1;
            app.angle_1_changed = true;
            app.AvantdroitCheckBoxValueChanged(app)
        end

        % Value changed function: Angle2
        function Angle2ValueChanged(app, event)
            app.angle_2 = app.Angle2.Value;
            app.angle_vector(2) = app.angle_2;
            app.angle_2_changed = true;
            app.AvantgaucheCheckBoxValueChanged(app)
        end

        % Value changed function: Angle3
        function Angle3ValueChanged(app, event)
            app.angle_3 = app.Angle3.Value;
            app.angle_vector(3) = app.angle_3;
            app.angle_3_changed = true;
            app.ArriredroitCheckBoxValueChanged(app)
        end

        % Value changed function: Angle4
        function Angle4ValueChanged(app, event)
            app.angle_4 = app.Angle4.Value;
            app.angle_vector(4) = app.angle_4;
            app.angle_4_changed = true;
            app.ArriregaucheCheckBoxValueChanged(app)
        end

        % Value changed function: NacelleCheckBox
        function NacelleCheckBoxValueChanged(app, event)
            app.state_nacelle = app.NacelleCheckBox.Value;

            delete(app.patch_nacelle)
            delete(app.norm_weight_nacelle)

            if app.state_nacelle == 1
                hold(app.Plot3D, 'on')
                disp("génération de la nacelle")

                Vertices_nacelle = [
                    app.nacelle_position.x1, app.nacelle_position.y1, app.nacelle_position.z1;
                    app.nacelle_position.x2, app.nacelle_position.y1, app.nacelle_position.z1;
                    app.nacelle_position.x2, app.nacelle_position.y2, app.nacelle_position.z1;
                    app.nacelle_position.x1, app.nacelle_position.y2, app.nacelle_position.z1;
                    app.nacelle_position.x1, app.nacelle_position.y1, app.nacelle_position.z2;
                    app.nacelle_position.x2, app.nacelle_position.y1, app.nacelle_position.z2;
                    app.nacelle_position.x2, app.nacelle_position.y2, app.nacelle_position.z2;
                    app.nacelle_position.x1, app.nacelle_position.y2, app.nacelle_position.z2;
                    ];

                app.patch_nacelle = patch(app.Plot3D, 'Vertices', Vertices_nacelle, 'Faces', app.faces, 'FaceColor', [0.5, 0, 0], 'FaceAlpha', 0.5);

                G_nacelle = [(app.nacelle_position.x1 + app.nacelle_position.x2)/2, (app.nacelle_position.y1 + app.nacelle_position.y2)/2, (app.nacelle_position.z1 + app.nacelle_position.z2)/2];

                % assignation de la masse du moteur ArG
                app.mass_nacelle = 0.5;
                app.weight_nacelle = app.mass_nacelle * app.grav;
                app.norm_weight_nacelle = quiver3(app.Plot3D, G_nacelle(1), G_nacelle(2), G_nacelle(3), 0, 0, -0.5, 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);

                % modification du centre de gravité
                app.mass_components(5) = app.mass_nacelle;
                app.CG_components(5, :) = G_nacelle;
                app.updateTotalCG();
            else
                disp("suppression de la nacelle")

                % modification du centre de gravité
                app.mass_components(5) = 0;
                app.CG_components(5, :) = [0, 0, 0];
                app.updateTotalCG();

                if ~isempty(app.patch_nacelle) && isvalid(app.patch_nacelle)
                    delete(app.patch_nacelle)
                    delete(app.norm_weight_nacelle)
                end
            end
        end

        % Value changed function: GrappinCheckBox
        function GrappinCheckBoxValueChanged(app, event)
            app.state_grappin = app.GrappinCheckBox.Value;

            delete(app.patch_grappin)
            delete(app.norm_weight_grappin)
            
            if app.state_grappin == 1
                hold(app.Plot3D, 'on')
                disp("génération du grappin")

                Vertices_grappin = [
                    app.grappling_position.x1, app.grappling_position.y1, app.grappling_position.z1;
                    app.grappling_position.x2, app.grappling_position.y1, app.grappling_position.z1;
                    app.grappling_position.x2, app.grappling_position.y2, app.grappling_position.z1;
                    app.grappling_position.x1, app.grappling_position.y2, app.grappling_position.z1
                    app.grappling_position.x1, app.grappling_position.y1, app.grappling_position.z2;
                    app.grappling_position.x2, app.grappling_position.y1, app.grappling_position.z2;
                    app.grappling_position.x2, app.grappling_position.y2, app.grappling_position.z2;
                    app.grappling_position.x1, app.grappling_position.y2, app.grappling_position.z2
                    ];

                disp(Vertices_grappin)

                app.patch_grappin = patch(app.Plot3D, 'Vertices', Vertices_grappin, 'faces', app.faces, 'FaceColor', [0, 1, 1], 'FaceAlpha', 0.5 );                
                
                G_grappin = [(app.grappling_position.x1 + app.grappling_position.x2)/2, (app.grappling_position.y1 + app.grappling_position.y2)/2, (app.grappling_position.z1 + app.grappling_position.z2)/2];

                % assignation de la masse du moteur ArG
                app.mass_grappin = 1;
                app.weight_grappin = app.mass_grappin * app.grav;
                app.norm_weight_grappin = quiver3(app.Plot3D, G_grappin(1), G_grappin(2), G_grappin(3), 0, 0, -0.5, 'Color', [0.68, 0.85, 0.90], 'LineWidth', 2);

                % modification du centre de gravité
                app.mass_components(6) = app.mass_grappin;
                app.CG_components(6, :) = G_grappin;
                app.updateTotalCG();
            else

                % modification du centre de gravité
                app.mass_components(6) = 0;
                app.CG_components(6, :) = [0, 0, 0];
                app.updateTotalCG();

                if ~isempty(app.patch_ar_d_motor) && isvalid(app.patch_ar_d_motor)
                    disp("suppression du grappin")
                    delete(app.patch_grappin)
                    delete(app.norm_weight_grappin)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Reprsentation3DTab = uitab(app.TabGroup);
            app.Reprsentation3DTab.Title = 'Représentation 3D';
            app.GridLayout2 = uigridlayout(app.Reprsentation3DTab);
            app.Plot3D = uiaxes(app.GridLayout2);
            title(app.Plot3D, 'Title')
            xlabel(app.Plot3D, 'X')
            ylabel(app.Plot3D, 'Y')
            zlabel(app.Plot3D, 'Z')
            app.GestiondescomposantsLabel = uilabel(app.GridLayout2);
            app.GestiondescomposantsLabel.Text = 'Gestion des composants';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.AvantdroitCheckBox = uicheckbox(app.GridLayout4);
            app.AvantdroitCheckBox.ValueChangedFcn = createCallbackFcn(app, @AvantdroitCheckBoxValueChanged, true);
            app.AvantdroitCheckBox.Text = 'Avant droit';
            app.AvantgaucheCheckBox = uicheckbox(app.GridLayout4);
            app.AvantgaucheCheckBox.ValueChangedFcn = createCallbackFcn(app, @AvantgaucheCheckBoxValueChanged, true);
            app.AvantgaucheCheckBox.Text = 'Avant gauche';
            app.ArriredroitCheckBox = uicheckbox(app.GridLayout4);
            app.ArriredroitCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArriredroitCheckBoxValueChanged, true);
            app.ArriredroitCheckBox.Text = 'Arrière droit';
            app.ArriregaucheCheckBox = uicheckbox(app.GridLayout4);
            app.ArriregaucheCheckBox.ValueChangedFcn = createCallbackFcn(app, @ArriregaucheCheckBoxValueChanged, true);
            app.ArriregaucheCheckBox.Text = 'Arrière gauche';
            app.MoteurLabel = uilabel(app.GridLayout4);
            app.MoteurLabel.Text = 'Moteur';
            app.AngleLabel = uilabel(app.GridLayout4);
            app.AngleLabel.Text = 'Angle';
            app.Angle1 = uieditfield(app.GridLayout4, 'numeric');
            app.Angle1.ValueChangedFcn = createCallbackFcn(app, @Angle1ValueChanged, true);
            app.Angle2 = uieditfield(app.GridLayout4, 'numeric');
            app.Angle2.ValueChangedFcn = createCallbackFcn(app, @Angle2ValueChanged, true);
            app.Angle3 = uieditfield(app.GridLayout4, 'numeric');
            app.Angle3.ValueChangedFcn = createCallbackFcn(app, @Angle3ValueChanged, true);
            app.Angle4 = uieditfield(app.GridLayout4, 'numeric');
            app.Angle4.ValueChangedFcn = createCallbackFcn(app, @Angle4ValueChanged, true);
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.AvDVSliderLabel = uilabel(app.GridLayout6);
            app.AvDVSliderLabel.Text = 'AvD [V]';
            app.AvDVSlider = uislider(app.GridLayout6);
            app.AvDVSlider.Limits = [0 6];
            app.AvGVSliderLabel = uilabel(app.GridLayout6);
            app.AvGVSliderLabel.Text = 'AvG [V]';
            app.AvGVSlider = uislider(app.GridLayout6);
            app.AvGVSlider.Limits = [0 6];
            app.ArDVSliderLabel = uilabel(app.GridLayout6);
            app.ArDVSliderLabel.Text = 'ArD [V]';
            app.ArDVSlider = uislider(app.GridLayout6);
            app.ArDVSlider.Limits = [0 6];
            app.ArGVSliderLabel = uilabel(app.GridLayout6);
            app.ArGVSliderLabel.Text = 'ArG [V]';
            app.ArGVSlider = uislider(app.GridLayout6);
            app.ArGVSlider.Limits = [0 6];
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.NacelleLabel = uilabel(app.GridLayout7);
            app.NacelleLabel.Text = 'Nacelle';
            app.GrappinLabel = uilabel(app.GridLayout7);
            app.GrappinLabel.Text = 'Grappin';
            app.NacelleCheckBox = uicheckbox(app.GridLayout7);
            app.NacelleCheckBox.ValueChangedFcn = createCallbackFcn(app, @NacelleCheckBoxValueChanged, true);
            app.NacelleCheckBox.Text = 'Nacelle';
            app.GrappinCheckBox = uicheckbox(app.GridLayout7);
            app.GrappinCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrappinCheckBoxValueChanged, true);
            app.GrappinCheckBox.Text = 'Grappin';
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.GridLayout5 = uigridlayout(app.PerformanceTab);
            app.AvD_plot = uiaxes(app.GridLayout5);
            title(app.AvD_plot, 'Title')
            xlabel(app.AvD_plot, 'X')
            ylabel(app.AvD_plot, 'Y')
            zlabel(app.AvD_plot, 'Z')
            app.ArD_plot = uiaxes(app.GridLayout5);
            title(app.ArD_plot, 'Title')
            xlabel(app.ArD_plot, 'X')
            ylabel(app.ArD_plot, 'Y')
            zlabel(app.ArD_plot, 'Z')
            app.AvG_plot = uiaxes(app.GridLayout5);
            title(app.AvG_plot, 'Title')
            xlabel(app.AvG_plot, 'X')
            ylabel(app.AvG_plot, 'Y')
            zlabel(app.AvG_plot, 'Z')
            app.ArG_plot = uiaxes(app.GridLayout5);
            title(app.ArG_plot, 'Title')
            xlabel(app.ArG_plot, 'X')
            ylabel(app.ArG_plot, 'Y')
            zlabel(app.ArG_plot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end